classdef xiangyingshijian < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        Label_14          matlab.ui.control.Label
        pingjungfangfa    matlab.ui.control.ListBox
        Label_13          matlab.ui.control.Label
        huixie            matlab.ui.control.Button
        dcgc              matlab.ui.control.NumericEditField
        Label_12          matlab.ui.control.Label
        dcwdsj            matlab.ui.control.NumericEditField
        Label_11          matlab.ui.control.Label
        dcxysj            matlab.ui.control.NumericEditField
        Label_10          matlab.ui.control.Label
        lianghuafangshi   matlab.ui.control.NumericEditField
        Label_9           matlab.ui.control.Label
        shurufanwei       matlab.ui.control.CheckBox
        shang             matlab.ui.control.NumericEditField
        Label_8           matlab.ui.control.Label
        xia               matlab.ui.control.NumericEditField
        Label_7           matlab.ui.control.Label
        pwm               matlab.ui.control.Table
        guochongpingjun   matlab.ui.control.NumericEditField
        Label_6           matlab.ui.control.Label
        wdsjavg           matlab.ui.control.NumericEditField
        Label_5           matlab.ui.control.Label
        xysjavg           matlab.ui.control.NumericEditField
        Label_4           matlab.ui.control.Label
        Button_4          matlab.ui.control.Button
        pinghuashuliang   matlab.ui.control.NumericEditField
        Label_3           matlab.ui.control.Label
        EditField         matlab.ui.control.NumericEditField
        Label_2           matlab.ui.control.Label
        Button_3          matlab.ui.control.Button
        Button_2          matlab.ui.control.Button
        Button            matlab.ui.control.Button
        wdsjbiao          matlab.ui.control.Table
        guochongbiao      matlab.ui.control.Table
        xysjbiao          matlab.ui.control.Table
        guochongtu        matlab.ui.control.UIAxes
        wdsjtu            matlab.ui.control.UIAxes
        xysjtu            matlab.ui.control.UIAxes
        xiangyingxinxitu  matlab.ui.control.UIAxes
    end


    properties (Access = private)
        wenjianjiaweizhi %文件夹位
        pwmjianyi %pwm建议
        Fs
        wenjianxinxi
        selection


    end
    
    methods (Access = private)
        
        function  gengxintubiao(app)
            %根据图更新平均和表

            bar3(app.xysjtu,app.xysjbiao.Data);
            set(app.xysjtu,'XTickLabel',app.wenjianxinxi.row,'YTickLabel',app.wenjianxinxi.row);
            bar3(app.wdsjtu,app.wdsjbiao.Data);
            set(app.wdsjtu,'XTickLabel',app.wenjianxinxi.row,'YTickLabel',app.wenjianxinxi.row);

            bar3(app.guochongtu,app.guochongbiao.Data);
            set(app.guochongtu,'XTickLabel',app.wenjianxinxi.row,'YTickLabel',app.wenjianxinxi.row);

            app.xysjavg.Value=mean(app.xysjbiao.Data(app.xysjbiao.Data~=0));
            app.wdsjavg.Value=mean(app.wdsjbiao.Data(app.wdsjbiao.Data~=0));
            app.guochongpingjun.Value=mean(app.guochongbiao.Data(app.guochongbiao.Data~=0));
            
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           
            app.Fs=186576;
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            %%选择文件夹回调。。


            app.wenjianjiaweizhi = uigetdir(matlabroot,'打开响应时间目录');

            %app.wenjianjiaweizhi='/Users/renjieah/Desktop/huaweidisplaytest/od-0';


            app.wenjianxinxi=readfolder(app.wenjianjiaweizhi);


            pwmshuju=readmatrix([app.wenjianjiaweizhi,'/',app.wenjianxinxi.filetable{1,end}],"Range",[5,3:3]);


            app.pwmjianyi=PwmFs(pwmshuju,app.Fs);
            s = uistyle('HorizontalAlignment','left');
            addStyle(app.pwm,s)
            app.pwm.Data=app.pwmjianyi(:,3);
            app.pinghuashuliang.Value = app.pwmjianyi(1,3);%把傅立叶转换后平滑推荐值赋值


        end

        % Cell selection callback: pwm
        function pwmCellSelection(app, event)
            %选中推荐的自动赋值
            indices = event.Indices;
            a=indices(1,1);
            app.pinghuashuliang.Value = app.pwmjianyi(a,3);
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            %批量计算
            app.shurufanwei.Value=0;
            app.shurufanwei.Enable=0;

            guochongxinxi=piliangjisuan(app.wenjianjiaweizhi,app.Fs,app.pinghuashuliang.Value,app.lianghuafangshi.Value,app.EditField.Value,app.pingjungfangfa.Value);


            app.xysjbiao.Data = guochongxinxi.xiangyingshijian;
            app.xysjbiao.ColumnName=app.wenjianxinxi.row.';
            app.xysjbiao.RowName=app.wenjianxinxi.row;

            app.wdsjbiao.Data=guochongxinxi.wendingshijian;
            app.wdsjbiao.ColumnName=app.wenjianxinxi.row.';
            app.wdsjbiao.RowName=app.wenjianxinxi.row;

            app.guochongbiao.Data=guochongxinxi.guochong;
            app.guochongbiao.ColumnName=app.wenjianxinxi.row.';
            app.guochongbiao.RowName=app.wenjianxinxi.row;

           gengxintubiao(app);

            app.shurufanwei.Enable=1;


        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            xysjbiaoSelectionChanged(app, event)
        end

        % Button pushed function: huixie
        function huixieButtonPushed(app, event)
            if app.dcxysj ~= 0
                %判断是否为空并回写，并重新作图


                app.xysjbiao.Data(app.selection(1,1),app.selection(1,2)) = app.dcxysj.Value;
                app.wdsjbiao.Data(app.selection(1,1),app.selection(1,2))=app.dcwdsj.Value;
                app.guochongbiao.Data(app.selection(1,1),app.selection(1,2))=app.dcgc.Value;
                gengxintubiao(app);


            end
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            [filename_out, ~, FileIndex] = uiputfile('*.XLS','文件另存为');
            
             if FileIndex == 0  % 如果选择了cancel    
 	          return;
             else

                 
                writecell(app.xysjbiao.ColumnName.', filename_out,'Sheet',1,"Range",'B1'); % 将表头置于Excle第一行
                writecell(app.xysjbiao.RowName, filename_out,'Sheet',1,"Range",'A2')
                writematrix(app.xysjbiao.DisplayData, filename_out,'Sheet',1,"Range",'B2')
                writematrix('平均响应时间', filename_out,'Sheet',1,"Range",'A10')
                writematrix(app.xysjavg.Value, filename_out,'Sheet',1,"Range",'B10')


                writecell(app.wdsjbiao.ColumnName.', filename_out,'Sheet',1,"Range",'B11'); % 将表头置于Excle第一行
                writecell(app.wdsjbiao.RowName, filename_out,'Sheet',1,"Range",'A12')
                writematrix(app.wdsjbiao.DisplayData, filename_out,'Sheet',1,"Range",'B12')
                writematrix('平均稳定时间', filename_out,'Sheet',1,"Range",'A20')
                writematrix(app.wdsjavg.Value, filename_out,'Sheet',1,"Range",'B20')

                writecell(app.guochongbiao.ColumnName.', filename_out,'Sheet',1,"Range",'B21'); % 将表头置于Excle第一行
                writecell(app.guochongbiao.RowName, filename_out,'Sheet',1,"Range",'A22')
                writematrix(app.guochongbiao.DisplayData, filename_out,'Sheet',1,"Range",'B22')
                writematrix('平均过冲', filename_out,'Sheet',1,"Range",'A30')
                writematrix(app.guochongpingjun.Value, filename_out,'Sheet',1,"Range",'B30')


             end

        end

        % Callback function
        function xysjbiaoSelectionChanged(app, event)


        end

        % Cell selection callback: xysjbiao
        function xysjbiaoCellSelection(app, event)
          
            
             %选择表格后自动刷新出过冲图

            app.selection = event.Indices;


            x=char(app.wenjianxinxi.filetable{app.selection(1,1),app.selection(1,2)});

            lujing= [app.wenjianjiaweizhi '/' x];

            YuanShiShuJu=readmatrix(lujing,"Range",[5,3:3]);
            b=topq(YuanShiShuJu,app.lianghuafangshi.Value);%量化方式

            %平均
            smoothedData = smoothdata(b,app.pingjungfangfa.Value,app.pinghuashuliang.Value);
            [l,~]=size(smoothedData);
            times=linspace(0,l/app.Fs,l).';
            if app.shurufanwei.Value == 1
                level=[app.xia.Value app.shang.Value];
            else
                level=[0,0];
            end

            a=shoot(smoothedData,app.Fs,app.EditField.Value,level);
            app.dcxysj.Value=a.xiangyingshijian;
            app.dcwdsj.Value=a.wendingshijian;
            app.dcgc.Value=a.os;

            plot(app.xiangyingxinxitu,times,b,"Color",[77 190 238]/255,"DisplayName","输入数据")
            hold(app.xiangyingxinxitu,"on")
            plot(app.xiangyingxinxitu,times,smoothedData,"Color",[0 114 189]/255,"LineWidth",1.5,"DisplayName","平滑数据")
            yline(app.xiangyingxinxitu,a.levels(1),'--',"Color",[255,0,0]/255,DisplayName='低电平')
            yline(app.xiangyingxinxitu,a.levels(2),'--',"Color",[255,0,0]/255,DisplayName='高电平')
            scatter(app.xiangyingxinxitu,[a.t1 a.t2],[a.l1 a.l2],100,'blue','filled','p',DisplayName='响应计算点')
            scatter(app.xiangyingxinxitu,a.osinst,a.oslev,50,'red','o',DisplayName='最大值')
            scatter(app.xiangyingxinxitu,a.sinst,a.slev,100,'red','x',DisplayName='稳定点')
            hold(app.xiangyingxinxitu,"off")
            legend(app.xiangyingxinxitu)

            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.xiangyingxinxitu = uiaxes(app.UIFigure);
            title(app.xiangyingxinxitu, {''; ''})
            xlabel(app.xiangyingxinxitu, 'time(s)')
            zlabel(app.xiangyingxinxitu, 'Z')
            app.xiangyingxinxitu.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.xysjtu = uiaxes(app.UIFigure);
            title(app.xysjtu, '响应时间')
            xlabel(app.xysjtu, '结束')
            ylabel(app.xysjtu, '开始')
            zlabel(app.xysjtu, '响应时间')
            app.wdsjtu = uiaxes(app.UIFigure);
            title(app.wdsjtu, '稳定时间')
            xlabel(app.wdsjtu, '结束')
            ylabel(app.wdsjtu, '开始')
            zlabel(app.wdsjtu, '稳定时间')
            app.guochongtu = uiaxes(app.UIFigure);
            title(app.guochongtu, '过冲')
            xlabel(app.guochongtu, '结束')
            ylabel(app.guochongtu, '开始')
            zlabel(app.guochongtu, '过冲比例')
            app.xysjbiao = uitable(app.UIFigure);
            app.xysjbiao.ColumnName = '';
            app.xysjbiao.RowName = {};
            app.xysjbiao.CellSelectionCallback = createCallbackFcn(app, @xysjbiaoCellSelection, true);
            app.guochongbiao = uitable(app.UIFigure);
            app.guochongbiao.ColumnName = '';
            app.guochongbiao.RowName = {};
            app.wdsjbiao = uitable(app.UIFigure);
            app.wdsjbiao.ColumnName = '';
            app.wdsjbiao.RowName = {};
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '选择文件夹';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '全部计算';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '单个计算';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = {'测量位置'; ''; ''};
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField.Limits = [0 50];
            app.EditField.Value = 10;
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '平滑数量';
            app.pinghuashuliang = uieditfield(app.UIFigure, 'numeric');
            app.pinghuashuliang.Limits = [1 Inf];
            app.pinghuashuliang.Value = 1;
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '导出结果';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '响应时间平均';
            app.xysjavg = uieditfield(app.UIFigure, 'numeric');
            app.xysjavg.Editable = 'off';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '稳定时间平均';
            app.wdsjavg = uieditfield(app.UIFigure, 'numeric');
            app.wdsjavg.Editable = 'off';
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = '过冲平均';
            app.guochongpingjun = uieditfield(app.UIFigure, 'numeric');
            app.guochongpingjun.Editable = 'off';
            app.pwm = uitable(app.UIFigure);
            app.pwm.ColumnName = '';
            app.pwm.RowName = {};
            app.pwm.CellSelectionCallback = createCallbackFcn(app, @pwmCellSelection, true);
            app.Label_7 = uilabel(app.UIFigure);
            app.Label_7.Text = '下';
            app.xia = uieditfield(app.UIFigure, 'numeric');
            app.Label_8 = uilabel(app.UIFigure);
            app.Label_8.Text = '上';
            app.shang = uieditfield(app.UIFigure, 'numeric');
            app.shurufanwei = uicheckbox(app.UIFigure);
            app.shurufanwei.Text = '输入范围';
            app.Label_9 = uilabel(app.UIFigure);
            app.Label_9.Text = {'量化方式'; ''};
            app.lianghuafangshi = uieditfield(app.UIFigure, 'numeric');
            app.lianghuafangshi.Limits = [0 3];
            app.Label_10 = uilabel(app.UIFigure);
            app.Label_10.Text = '响应时间';
            app.dcxysj = uieditfield(app.UIFigure, 'numeric');
            app.Label_11 = uilabel(app.UIFigure);
            app.Label_11.Text = '稳定时间';
            app.dcwdsj = uieditfield(app.UIFigure, 'numeric');
            app.Label_12 = uilabel(app.UIFigure);
            app.Label_12.Text = '过冲';
            app.dcgc = uieditfield(app.UIFigure, 'numeric');
            app.huixie = uibutton(app.UIFigure, 'push');
            app.huixie.ButtonPushedFcn = createCallbackFcn(app, @huixieButtonPushed, true);
            app.huixie.Text = '回写';
            app.Label_13 = uilabel(app.UIFigure);
            app.Label_13.Text = '平滑';
            app.pingjungfangfa = uilistbox(app.UIFigure);
            app.pingjungfangfa.Items = {'移动中位数', '稳健二次回归', '移动平均', '高斯移动平均值', '稳健线性回归'};
            app.pingjungfangfa.ItemsData = {'movmedian', 'rloess', 'movmean', 'gaussian', 'rlowess'};
            app.pingjungfangfa.Value = 'movmedian';
            app.Label_14 = uilabel(app.UIFigure);
            app.Label_14.Text = '推荐平滑';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = xiangyingshijian

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end